classdef mainForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TrainingDataButton  matlab.ui.control.Button
        TestDataButton      matlab.ui.control.Button
        PengenalanWajahdenganMetodePCALabel  matlab.ui.control.Label
        LabelProcess        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: TrainingDataButton
        function TrainingDataButtonPushed(app, event)
            app.LabelProcess.Text = "Processing to open Form Training ....."; pause(0.00000000000001);
            formass = 'train'; 
            assignin('base','formass',formass);
            appCall 
            
            app.LabelProcess.Text = "Form Training is Open"; pause(0.00000000000001);
        end

        % Button pushed function: TestDataButton
        function TestDataButtonPushed(app, event)
            app.LabelProcess.Text = "Processing to open Form Testing ....."; pause(0.00000000000001);
            formass = 'test'; 
            assignin('base','formass',formass);
            appCall 
            
            app.LabelProcess.Text = "Form Testing is Open"; pause(0.00000000000001);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TrainingDataButton = uibutton(app.UIFigure, 'push');
            app.TrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingDataButtonPushed, true);
            app.TrainingDataButton.Text = 'Training Data';
            app.TestDataButton = uibutton(app.UIFigure, 'push');
            app.TestDataButton.ButtonPushedFcn = createCallbackFcn(app, @TestDataButtonPushed, true);
            app.TestDataButton.Text = 'Test Data';
            app.PengenalanWajahdenganMetodePCALabel = uilabel(app.UIFigure);
            app.PengenalanWajahdenganMetodePCALabel.Text = {'Pengenalan Wajah dengan '; 'Metode PCA'};
            app.LabelProcess = uilabel(app.UIFigure);
            app.LabelProcess.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainForm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end